classdef ProyectoSistemasV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ArchivoMenu                  matlab.ui.container.Menu
        NuevaMenu                    matlab.ui.container.Menu
        GuardarMenu                  matlab.ui.container.Menu
        ModificarMenu                matlab.ui.container.Menu
        BorrarMenu                   matlab.ui.container.Menu
        ExportarSealMenu             matlab.ui.container.Menu
        SalirMenu                    matlab.ui.container.Menu
        HerramientasMenu             matlab.ui.container.Menu
        NormalizacinMenu             matlab.ui.container.Menu
        FiltrarMenu                  matlab.ui.container.Menu
        EntradadesistemaHnMenu       matlab.ui.container.Menu
        FiltroIIRMenu                matlab.ui.container.Menu
        PasabajosMenu                matlab.ui.container.Menu
        PasaaltosMenu                matlab.ui.container.Menu
        EliminarNANeINFMenu          matlab.ui.container.Menu
        SubMuestrearMenu             matlab.ui.container.Menu
        SobreMuestrearMenu           matlab.ui.container.Menu
        DesplazarMenu                matlab.ui.container.Menu
        AdelantoMenu                 matlab.ui.container.Menu
        AtrasoMenu                   matlab.ui.container.Menu
        InvertirSealMenu             matlab.ui.container.Menu
        ProducirEcoMenu              matlab.ui.container.Menu
        OperarMenu                   matlab.ui.container.Menu
        ConvertirMonofnicaMenu       matlab.ui.container.Menu
        ConvertirEstereofnicaMenu    matlab.ui.container.Menu
        Sumar2SealesMenu             matlab.ui.container.Menu
        Multiplicar2SealesMenu       matlab.ui.container.Menu
        CrearSealEstereoMenu         matlab.ui.container.Menu
        VertransformadadeFurierMenu  matlab.ui.container.Menu
        VerSpectogramaMenu           matlab.ui.container.Menu
        AudioMenu                    matlab.ui.container.Menu
        AcercadeMenu                 matlab.ui.container.Menu
        InfoMenu                     matlab.ui.container.Menu
        Version20Menu                matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout2                  matlab.ui.container.GridLayout
        SignalCbox                   matlab.ui.control.DropDown
        Graficas                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %Parámetro de la señal
        tipo;
        Ganancia=[];FrecuenciaAnaloga=[];FrecuenciaMuestreo=[];Fase=[];Desplazamiento=[];
        

        %Señales
        Signals = {}
        Signal_New = {}

        %Tiempos
        n_inicio=[];
        n_final=[];
        ni_New = []
        nf_New = []
        
        % Variables glovales de aplicacion
        Cbidx = 0; % Indice del DropDown, lista desplegable o Combobox 
        
    end
    
    methods (Access = public)
        
        % Funcion para actualizar la Grafica y los parámetros desde la
        % simulacion de señales
        function SignalUpdate(app, G,FA,FM,F,D,ni,nf,S)
            % Parametros que vienen desde  la generación de la señal en modo
            % simulación
            app.Ganancia = G;
            app.FrecuenciaAnaloga = FA;
            app.FrecuenciaMuestreo = FM;
            app.Fase = F;
            app.Desplazamiento = D;
            app.n_inicio = ni;
            app.n_final = nf;
            % Dado que Sond distintas Señales se almasena la nueva señal en
            % la Matrix de señales
            app.Signals = [app.Signals;S]
            n = ni:nf
            % De igual forma se alamcena su respectivo vector tiempo para
            % la señal x
            app.N = [app.N, n]
            

            app.Graficar(n,  S, 'Seno', 'Tiempo', 'Amplitud')
            
            
            [f, c] = size(app.Signals)
            for i=f
                app.SignalCbox.Items= "Señal "+i
            end
        end

        %Funcion para graficar las señales
        function Graficar(app, IndiceSignal) 
            cla(app.Graficas, 'reset'); 

            n = app.n_inicio(IndiceSignal):app.n_final(IndiceSignal);
            stem(app.Graficas, n, app.Signals{IndiceSignal}, 'b', 'filled'); % Usando Stem para graficar en modo discreto 

            title(app.Graficas, app.SignalCbox.Value); %Parametros de Titilo
            xlabel(app.Graficas, "Tiempo");
            ylabel(app.Graficas, "Amplitud");

            % Cambiar color del título y etiquetas de los ejes a blanco
            app.Graficas.Title.Color = 'w';   % Título blanco
            app.Graficas.XLabel.Color = 'w';  % Etiqueta del eje X blanca
            app.Graficas.YLabel.Color = 'w';  % Etiqueta del eje Y blanca
            
            % Cambiar color de los números de los ejes a blanco
            app.Graficas.XColor = 'w';  % Color de los números del eje X
            app.Graficas.YColor = 'w';  % Color de los números del eje Y
            grid(app.Graficas, 'on');   % Activando la grilla
            app.Graficas.GridColor = [0.85 0.37 0.17];  % Color de la grilla
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: NormalizacinMenu, NuevaMenu
        function GenerarnuevasealMenuSelected(app, event)
            f = length(app.Signals); % Capturar cantidad de Señales creadas

           
            %************************************************************
            app2 = Crear(); % Crea la nueva app
            Signal = [];
            % Pausa la ejecución hasta que el usuario cierre la ventana
            uiwait(app2.UIFigure);
            
                
            if(app2.Tipo == 2)% Tipo 1 son las señales simuladas
                Signal = app2.Signal;
                app.Ganancia = [app.Ganancia, app2.Ganancia];
                app.FrecuenciaAnaloga = [app.FrecuenciaAnaloga , app2.FrecuenciaAnaloga];
                app.FrecuenciaMuestreo = [app.FrecuenciaMuestreo , app2.FrecuenciaMuestreo];
                app.Fase = [app.Fase, app2.Fase];
                app.Desplazamiento = [app.Desplazamiento, app2.Desplazamiento];
                app.n_inicio = [app.n_inicio,  app2.n_inicio];
                app.n_final = [app.n_final,  app2.n_final];
            elseif(app2.Tipo == 3)
                Signal = app2.Signal;
                app.FrecuenciaAnaloga = [app.FrecuenciaAnaloga , 0];
                app.FrecuenciaMuestreo = [app.FrecuenciaMuestreo , app2.FrecuenciaMuestreo];
                app.Fase = [app.Fase, 0];
                app.Desplazamiento = 0;
                app.n_inicio = [app.n_inicio,  app2.n_inicio];
                app.n_final = [app.n_final,  app2.n_final];
            end

            delete(app2);
            clear app2;
            %************************************************************
            if isempty(app.Signals)     % Si no hay señales se crea la nueva etiqueta en el dropdown

                app.SignalCbox.Items= "Señal 1";
                app.Signals{1} = Signal; % Primera señal

            else                        % Si ya hay señales se agrega una nueva señal con el indice de una nueva señal

                app.SignalCbox.Items = [app.SignalCbox.Items, {['Señal ' num2str(f+1)]}];
                app.SignalCbox.ValueIndex = f+1; % Se pone el dropdown por defecto en la nueva señal
                app.Signals{end+1} = Signal;

            end
            app.Signal_New = app.Signals;
            app.ni_New = app.n_inicio;
            app.nf_New = app.n_final;
            app.Graficar(f+1); % Graficar señal
            

        end

        % Menu selected function: EntradadesistemaHnMenu
        function EntradadesistemaHnMenuSelected(app, event)
           
        end

        % Menu selected function: FiltroIIRMenu
        function FiltroIIRMenuSelected(app, event)
            
        end

        % Menu selected function: BorrarMenu
        function BorrarMenuSelected(app, event)
            %f= length(app.Signals);% se consigue el valor maximo de items en las señales
            %disp("Signals = "+num2str(f));
            if(app.Cbidx>1)
                disp(app.Cbidx);
                app.Signals(app.Cbidx) = [];    % Se elimina el valor de la señal que se quiere de 
                                                % Cbidx = indice del cbox 
                app.Signal_New(app.Cbidx) = []; % Borra toda la información de copias y tiempos
                                                % ya que los arreglos van desde 1 y el dropdown desde 0
                
                app.n_inicio(app.Cbidx) = [];
                app.n_final(app.Cbidx) = [];
                app.ni_New(app.Cbidx) = [];
                app.nf_New(app.Cbidx) = [];

                app.Ganancia(app.Cbidx) = [];
                app.FrecuenciaAnaloga(app.Cbidx) = [];
                app.FrecuenciaMuestreo(app.Cbidx) = [];
                app.Fase(app.Cbidx) = [];
                app.Desplazamiento(app.Cbidx) = [];

                
                f= length(app.Signals);
                app.SignalCbox.Items(f) = [];% se elimina el ultimo valor del dropdown dejando la cantidad exacta de items para las señales  
                app.SignalCbox.ValueIndex = 1;

                if isempty(app.Signals) % Si no hay señales solo borra lo que hay en el marco sino grafica la primera
                    cla(app.Graficas, 'reset'); 
                else
                    app.Graficar(1);
                end
                app.Cbidx = 1;
                app.SignalCbox.Items= "Señal 1";
                for i=2:f
                    app.SignalCbox.Items = [app.SignalCbox.Items, {['Señal ' num2str(i)]}];
                    
                end
            else
                app.Cbidx = 0;
                app.Signals = {};
                app.Signal_New = [];
                app.n_inicio = [];
                app.n_final = [];
                app.ni_New = [];
                app.nf_New = [];
                app.Ganancia=[];app.FrecuenciaAnaloga=[];app.FrecuenciaMuestreo=[];app.Fase=[];app.Desplazamiento=[];
                app.SignalCbox.Items = 'Signals';
            end
        end

        % Value changed function: SignalCbox
        function SignalCboxValueChanged(app, event)
            app.Cbidx = app.SignalCbox.ValueIndex;% Se consigue el indice del valor del dropdown
            app.Graficar(app.Cbidx); % Graficar señal

        end

        % Menu selected function: GuardarMenu
        function GuardarMenuSelected(app, event)
            app.Signals = app.Signal_New;
            app.ni = app.ni_New;
            app.nf = app.nf_New;
        end

        % Menu selected function: ExportarSealMenu
        function ExportarSealMenuSelected(app, event)
             % Guardar en equipo
            countName = 1;
            % Verificacion si el archivo txt existe
            while exist(['Signal' num2str(countName) '.txt'], 'file') == 2
                countName = countName + 1;
            end

            archivoName = ['Signal' num2str(countName) '.txt'];
            archivo = fopen(archivoName, 'w');
            
            fprintf(archivo, '%d\n', app.Signals{app.Cbidx+1});% Escribir en el documento
            fclose(archivo);

            msgbox(['Señal exportada en ' archivoName], 'Confirmación', 'help');

            % Verificacion si el archivo wav existe
            %while exist(['Signal' num2str(countName) '.wav'], 'file') == 2
            %    countName = countName + 1;
            %end
            %audiowrite(['Signal' num2str(countName) '.wav'],app.SignalNueva,app.Fs);%Escribir como audio
            % Guardar en sistema
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ArchivoMenu = uimenu(app.UIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.NuevaMenu = uimenu(app.ArchivoMenu);
            app.NuevaMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerarnuevasealMenuSelected, true);
            app.NuevaMenu.Text = 'Nueva';
            app.GuardarMenu = uimenu(app.ArchivoMenu);
            app.GuardarMenu.MenuSelectedFcn = createCallbackFcn(app, @GuardarMenuSelected, true);
            app.GuardarMenu.Separator = 'on';
            app.GuardarMenu.Text = 'Guardar';
            app.ModificarMenu = uimenu(app.ArchivoMenu);
            app.ModificarMenu.Text = 'Modificar';
            app.BorrarMenu = uimenu(app.ArchivoMenu);
            app.BorrarMenu.MenuSelectedFcn = createCallbackFcn(app, @BorrarMenuSelected, true);
            app.BorrarMenu.Text = 'Borrar';
            app.ExportarSealMenu = uimenu(app.ArchivoMenu);
            app.ExportarSealMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportarSealMenuSelected, true);
            app.ExportarSealMenu.Text = 'Exportar Señal';
            app.SalirMenu = uimenu(app.ArchivoMenu);
            app.SalirMenu.Separator = 'on';
            app.SalirMenu.Text = 'Salir';
            app.HerramientasMenu = uimenu(app.UIFigure);
            app.HerramientasMenu.Text = 'Herramientas';
            app.NormalizacinMenu = uimenu(app.HerramientasMenu);
            app.NormalizacinMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerarnuevasealMenuSelected, true);
            app.NormalizacinMenu.Text = 'Normalización ';
            app.FiltrarMenu = uimenu(app.HerramientasMenu);
            app.FiltrarMenu.Text = 'Filtrar';
            app.EntradadesistemaHnMenu = uimenu(app.FiltrarMenu);
            app.EntradadesistemaHnMenu.MenuSelectedFcn = createCallbackFcn(app, @EntradadesistemaHnMenuSelected, true);
            app.EntradadesistemaHnMenu.Text = 'Entrada de sistema H(n)';
            app.FiltroIIRMenu = uimenu(app.FiltrarMenu);
            app.FiltroIIRMenu.MenuSelectedFcn = createCallbackFcn(app, @FiltroIIRMenuSelected, true);
            app.FiltroIIRMenu.Text = 'Filtro IIR';
            app.PasabajosMenu = uimenu(app.FiltrarMenu);
            app.PasabajosMenu.Text = 'Pasa bajos';
            app.PasaaltosMenu = uimenu(app.FiltrarMenu);
            app.PasaaltosMenu.Text = 'Pasa altos';
            app.EliminarNANeINFMenu = uimenu(app.HerramientasMenu);
            app.EliminarNANeINFMenu.Text = 'Eliminar NAN e INF';
            app.SubMuestrearMenu = uimenu(app.HerramientasMenu);
            app.SubMuestrearMenu.Separator = 'on';
            app.SubMuestrearMenu.Text = 'Sub-Muestrear';
            app.SobreMuestrearMenu = uimenu(app.HerramientasMenu);
            app.SobreMuestrearMenu.Text = 'Sobre-Muestrear';
            app.DesplazarMenu = uimenu(app.HerramientasMenu);
            app.DesplazarMenu.Text = 'Desplazar';
            app.AdelantoMenu = uimenu(app.DesplazarMenu);
            app.AdelantoMenu.Text = 'Adelanto';
            app.AtrasoMenu = uimenu(app.DesplazarMenu);
            app.AtrasoMenu.Text = 'Atraso';
            app.InvertirSealMenu = uimenu(app.HerramientasMenu);
            app.InvertirSealMenu.Separator = 'on';
            app.InvertirSealMenu.Text = 'Invertir Señal';
            app.ProducirEcoMenu = uimenu(app.HerramientasMenu);
            app.ProducirEcoMenu.Text = 'Producir Eco';
            app.OperarMenu = uimenu(app.UIFigure);
            app.OperarMenu.Text = 'Operar';
            app.ConvertirMonofnicaMenu = uimenu(app.OperarMenu);
            app.ConvertirMonofnicaMenu.Text = 'Convertir Monofónica';
            app.ConvertirEstereofnicaMenu = uimenu(app.OperarMenu);
            app.ConvertirEstereofnicaMenu.Text = 'Convertir Estereofónica';
            app.Sumar2SealesMenu = uimenu(app.OperarMenu);
            app.Sumar2SealesMenu.Separator = 'on';
            app.Sumar2SealesMenu.Text = 'Sumar 2 Señales';
            app.Multiplicar2SealesMenu = uimenu(app.OperarMenu);
            app.Multiplicar2SealesMenu.Text = 'Multiplicar 2 Señales';
            app.CrearSealEstereoMenu = uimenu(app.OperarMenu);
            app.CrearSealEstereoMenu.Text = 'Crear Señal Estereo';
            app.VertransformadadeFurierMenu = uimenu(app.OperarMenu);
            app.VertransformadadeFurierMenu.Separator = 'on';
            app.VertransformadadeFurierMenu.Text = 'Ver transformada de Furier';
            app.VerSpectogramaMenu = uimenu(app.OperarMenu);
            app.VerSpectogramaMenu.Text = 'Ver Spectograma';
            app.AudioMenu = uimenu(app.OperarMenu);
            app.AudioMenu.Separator = 'on';
            app.AudioMenu.Text = 'Audio';
            app.AcercadeMenu = uimenu(app.UIFigure);
            app.AcercadeMenu.Text = 'Acerca de';
            app.InfoMenu = uimenu(app.AcercadeMenu);
            app.InfoMenu.Text = 'Info';
            app.Version20Menu = uimenu(app.AcercadeMenu);
            app.Version20Menu.Separator = 'on';
            app.Version20Menu.Text = 'Version 2.0';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Graficas = uiaxes(app.GridLayout);
            title(app.Graficas, 'Title')
            xlabel(app.Graficas, 'X')
            ylabel(app.Graficas, 'Y')
            zlabel(app.Graficas, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SignalCbox = uidropdown(app.GridLayout2);
            app.SignalCbox.Items = {'Signals'};
            app.SignalCbox.ValueChangedFcn = createCallbackFcn(app, @SignalCboxValueChanged, true);
            app.SignalCbox.Value = 'Signals';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProyectoSistemasV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end